classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        HistogramTab                matlab.ui.container.Tab
        Panel_29                    matlab.ui.container.Panel
        UIAxes_HistIn               matlab.ui.control.UIAxes
        Panel_27                    matlab.ui.container.Panel
        BrowseFileButton_8          matlab.ui.control.Button
        InputLabel_7                matlab.ui.control.Label
        Image_HistIn                matlab.ui.control.Image
        ImageEnhancementTab         matlab.ui.container.Tab
        TabGroup2                   matlab.ui.container.TabGroup
        ImageBrighteningTab         matlab.ui.container.Tab
        Panel_14                    matlab.ui.container.Panel
        UIAxes_BrighteningOut       matlab.ui.control.UIAxes
        Panel_13                    matlab.ui.container.Panel
        OutputLabel_4               matlab.ui.control.Label
        Image_BrighteningOut        matlab.ui.control.Image
        Panel_12                    matlab.ui.container.Panel
        UIAxes_BrightningIn         matlab.ui.control.UIAxes
        Panel_11                    matlab.ui.container.Panel
        bEditField                  matlab.ui.control.NumericEditField
        bEditFieldLabel             matlab.ui.control.Label
        aEditField                  matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        ComputeButton               matlab.ui.control.Button
        sarbLabel                   matlab.ui.control.Label
        BrowseFileButton_4          matlab.ui.control.Button
        InputLabel_3                matlab.ui.control.Label
        Image_BrighteningIn         matlab.ui.control.Image
        LogTransformationTab        matlab.ui.container.Tab
        Panel_18                    matlab.ui.container.Panel
        UIAxes_LogOut               matlab.ui.control.UIAxes
        Panel_17                    matlab.ui.container.Panel
        OutputLabel_5               matlab.ui.control.Label
        Image_LogOut                matlab.ui.control.Image
        Panel_16                    matlab.ui.container.Panel
        UIAxes_LogIn                matlab.ui.control.UIAxes
        Panel_15                    matlab.ui.container.Panel
        cEditField                  matlab.ui.control.NumericEditField
        cEditFieldLabel             matlab.ui.control.Label
        ComputeButton_2             matlab.ui.control.Button
        sclog1rLabel                matlab.ui.control.Label
        BrowseFileButton_5          matlab.ui.control.Button
        InputLabel_4                matlab.ui.control.Label
        Image_LogIn                 matlab.ui.control.Image
        PowerTransformationTab      matlab.ui.container.Tab
        Panel_22                    matlab.ui.container.Panel
        UIAxes_PowerOut             matlab.ui.control.UIAxes
        Panel_21                    matlab.ui.container.Panel
        OutputLabel_6               matlab.ui.control.Label
        Image_PowerOut              matlab.ui.control.Image
        Panel_20                    matlab.ui.container.Panel
        UIAxes_PowerIn              matlab.ui.control.UIAxes
        Panel_19                    matlab.ui.container.Panel
        yEditField                  matlab.ui.control.NumericEditField
        yEditFieldLabel             matlab.ui.control.Label
        cEditField_2                matlab.ui.control.NumericEditField
        cEditField_2Label           matlab.ui.control.Label
        ComputeButton_3             matlab.ui.control.Button
        scryLabel                   matlab.ui.control.Label
        BrowseFileButton_6          matlab.ui.control.Button
        InputLabel_5                matlab.ui.control.Label
        Image_PowerIn               matlab.ui.control.Image
        ContrastStretchingTab       matlab.ui.container.Tab
        Panel_26                    matlab.ui.container.Panel
        UIAxes_ContrastOut          matlab.ui.control.UIAxes
        Panel_25                    matlab.ui.container.Panel
        OutputLabel_7               matlab.ui.control.Label
        Image_ContrastOut           matlab.ui.control.Image
        Panel_24                    matlab.ui.container.Panel
        UIAxes_ContrastIn           matlab.ui.control.UIAxes
        Panel_23                    matlab.ui.container.Panel
        BrowseFileButton_7          matlab.ui.control.Button
        InputLabel_6                matlab.ui.control.Label
        Image_ContrastIn            matlab.ui.control.Image
        HistogramEqualizationTab    matlab.ui.container.Tab
        Panel_4                     matlab.ui.container.Panel
        UIAxes_HistEqualizationOut  matlab.ui.control.UIAxes
        Panel_3                     matlab.ui.container.Panel
        UIAxes_HistEqualizationIn   matlab.ui.control.UIAxes
        Panel_2                     matlab.ui.container.Panel
        OutputLabel                 matlab.ui.control.Label
        Image_HistEqualIizationOut  matlab.ui.control.Image
        Panel                       matlab.ui.container.Panel
        BrowseFileButton            matlab.ui.control.Button
        InputLabel                  matlab.ui.control.Label
        Image_HistEqualIizationIn   matlab.ui.control.Image
        HistogramSpecificationTab   matlab.ui.container.Tab
        Panel_10                    matlab.ui.container.Panel
        UIAxes_HistSpecOut          matlab.ui.control.UIAxes
        Panel_9                     matlab.ui.container.Panel
        OutputLabel_3               matlab.ui.control.Label
        Image_HistSpecOut           matlab.ui.control.Image
        Panel_8                     matlab.ui.container.Panel
        UIAxes_HistSpecRef          matlab.ui.control.UIAxes
        Panel_7                     matlab.ui.container.Panel
        UIAxes_HistSpecIn           matlab.ui.control.UIAxes
        Panel_6                     matlab.ui.container.Panel
        BrowseFileButton_3          matlab.ui.control.Button
        ReferenceLabel              matlab.ui.control.Label
        Image_HistSpecRef           matlab.ui.control.Image
        Panel_5                     matlab.ui.container.Panel
        BrowseFileButton_2          matlab.ui.control.Button
        InputLabel_2                matlab.ui.control.Label
        Image_HistSpecIn            matlab.ui.control.Image
    end

    
    properties (Access = private)
        is_no_file_selected = true
        is_no_file_selected_ref = true
        
        img_in
        img_ref

        a_brightening
        b_brightening
        c_log_transform
        c_power_transform
        y_power_transform
    end
    
    methods (Access = private)
        
        function load_image(app)
            % Get file browsed
            [file, path] = uigetfile('*');
            if file == 0
                % No file selected
                app.is_no_file_selected = true;
            else
                app.is_no_file_selected = false;
                % Return the loaded image
                filepath = fullfile(path, file);
                disp(filepath);
                img = imread(filepath);
                disp(size(img));
                if (is_rgb_same(img) && size(img, 3) == 1)
                    % If grayscale, set to three channel
                    img = cat(3, img, img, img);
                end
                app.img_in = img;

            end
        end

        function load_image_ref(app)
            % Get file browsed
            [file, path] = uigetfile('*');
            if file == 0
                % No file selected
                app.is_no_file_selected_ref = true;
            else
                app.is_no_file_selected_ref = false;
                % Return the loaded image
                filepath = fullfile(path, file);
                disp(filepath);
                img = imread(filepath);
                if (is_rgb_same(img))
                    % If grayscale, set to three channel
                    img = cat(3, img, img, img);
                end
                
                app.img_ref = img;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height]; 
        end

        % Button pushed function: BrowseFileButton
        function hist_equalize(app, event)
            app.load_image();

            if (~app.is_no_file_selected)
                app.Image_HistEqualIizationIn.ImageSource = app.img_in;

                [raw_hist, equalized_hist, equalized_img] = hist_equalization(app.img_in);
                bar(app.UIAxes_HistEqualizationIn,raw_hist);
                bar(app.UIAxes_HistEqualizationOut,equalized_hist);
                app.Image_HistEqualIizationOut.ImageSource = equalized_img;
            end


        end

        % Button pushed function: BrowseFileButton_2
        function hist_specify(app, event)
            app.load_image();

            if (~app.is_no_file_selected)
                app.Image_HistSpecIn.ImageSource = app.img_in;

                if (~app.is_no_file_selected_ref)
                    [raw_hist_in, raw_hist_ref, specific_hist, specific_img] = hist_specification(app.img_in, app.img_ref);
                    bar(app.UIAxes_HistSpecIn,raw_hist_in);
                    bar(app.UIAxes_HistSpecRef,raw_hist_ref); 
                    bar(app.UIAxes_HistSpecOut,specific_hist);
                    app.Image_HistSpecOut.ImageSource = specific_img;
                end
            end
        end

        % Button pushed function: BrowseFileButton_3
        function hist_specify_ref(app, event)
            app.load_image_ref();

            if (~app.is_no_file_selected_ref)
                app.Image_HistSpecRef.ImageSource = app.img_ref;

                if (~app.is_no_file_selected)
                    [raw_hist_in, raw_hist_ref, specific_hist, specific_img] = hist_specification(app.img_in, app.img_ref);
                    bar(app.UIAxes_HistSpecIn,raw_hist_in);
                    bar(app.UIAxes_HistSpecRef,raw_hist_ref);
                    bar(app.UIAxes_HistSpecOut,specific_hist);
                    app.Image_HistSpecOut.ImageSource = specific_img;
                end
            end
        end

        % Button pushed function: BrowseFileButton_4
        function set_img_brightening(app, event)
            app.load_image();

            if (~app.is_no_file_selected)
                app.Image_BrighteningIn.ImageSource = app.img_in; 
            end
        end

        % Value changed function: aEditField
        function set_a_brightening(app, event)
            value = app.aEditField.Value;
            app.a_brightening = value;
        end

        % Value changed function: bEditField
        function set_b_brightening(app, event)
            value = app.bEditField.Value;
            app.b_brightening = value;
        end

        % Button pushed function: ComputeButton
        function compute_brightening(app, event)
            if (~app.is_no_file_selected)
                [raw_hist, enhanced_hist, enhanced_img] = brightening(app.img_in, double(app.a_brightening), double(app.b_brightening));
                app.Image_BrighteningOut.ImageSource = enhanced_img;

                bar(app.UIAxes_BrightningIn, raw_hist);
                bar(app.UIAxes_BrighteningOut, enhanced_hist);
            end
        end

        % Button pushed function: BrowseFileButton_5
        function set_img_log_transformation(app, event)
            app.load_image();

            if (~app.is_no_file_selected)
                app.Image_LogIn.ImageSource = app.img_in; 
            end
        end

        % Value changed function: cEditField
        function set_c_log_transform(app, event)
            value = app.cEditField.Value;
            app.c_log_transform = value;
        end

        % Button pushed function: ComputeButton_2
        function compute_log_transform(app, event)
            if (~app.is_no_file_selected)
                [raw_hist, enhanced_hist, enhanced_img] = log_transformation(app.img_in, double(app.c_log_transform));
                app.Image_LogOut.ImageSource = enhanced_img;

                bar(app.UIAxes_LogIn, raw_hist);
                bar(app.UIAxes_LogOut, enhanced_hist);
            end
        end

        % Button pushed function: BrowseFileButton_6
        function set_img_power_transform(app, event)
            app.load_image();

            if (~app.is_no_file_selected)
                app.Image_PowerIn.ImageSource = app.img_in; 
            end
        end

        % Value changed function: cEditField_2
        function set_c_power_transform(app, event)
            value = app.cEditField_2.Value;
            app.c_power_transform = value;
        end

        % Value changed function: yEditField
        function set_y_power_transform(app, event)
            value = app.yEditField.Value;
            app.y_power_transform = value;
        end

        % Button pushed function: ComputeButton_3
        function compute_power_transform(app, event)
            if (~app.is_no_file_selected)
                [raw_hist, enhanced_hist, enhanced_img] = power_transformation(app.img_in, double(app.c_power_transform), double(app.y_power_transform));
                app.Image_PowerOut.ImageSource = enhanced_img;

                bar(app.UIAxes_PowerIn, raw_hist);
                bar(app.UIAxes_PowerOut, enhanced_hist);
            end
        end

        % Button pushed function: BrowseFileButton_7
        function set_img_contrast_stretching(app, event)
            app.load_image();

            if (~app.is_no_file_selected)
                app.Image_ContrastIn.ImageSource = app.img_in;

                [raw_hist, enhanced_hist, enhanced_img] = contrast_stretching(app.img_in);
                bar(app.UIAxes_ContrastIn, raw_hist);
                bar(app.UIAxes_ContrastOut, enhanced_hist);
                app.Image_ContrastOut.ImageSource = enhanced_img;
            end
        end

        % Callback function
        function compute_contrast_stretching(app, event)
            
        end

        % Button pushed function: BrowseFileButton_8
        function show_histogram(app, event)
            app.load_image();

            if (~app.is_no_file_selected)
                app.Image_HistIn.ImageSource = app.img_in;
                hist = get_frequency(app.img_in);
                bar(app.UIAxes_HistIn, hist);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.Panel_27 = uipanel(app.HistogramTab);
            app.Image_HistIn = uiimage(app.Panel_27);
            app.InputLabel_7 = uilabel(app.Panel_27);
            app.InputLabel_7.Text = 'Input';
            app.BrowseFileButton_8 = uibutton(app.Panel_27, 'push');
            app.BrowseFileButton_8.ButtonPushedFcn = createCallbackFcn(app, @show_histogram, true);
            app.BrowseFileButton_8.Text = 'Browse File';
            app.Panel_29 = uipanel(app.HistogramTab);
            app.UIAxes_HistIn = uiaxes(app.Panel_29);
            title(app.UIAxes_HistIn, 'Input Histogram')
            zlabel(app.UIAxes_HistIn, 'Z')
            app.ImageEnhancementTab = uitab(app.TabGroup);
            app.ImageEnhancementTab.Title = 'Image Enhancement';
            app.TabGroup2 = uitabgroup(app.ImageEnhancementTab);
            app.ImageBrighteningTab = uitab(app.TabGroup2);
            app.ImageBrighteningTab.Title = 'Image Brightening';
            app.Panel_11 = uipanel(app.ImageBrighteningTab);
            app.Image_BrighteningIn = uiimage(app.Panel_11);
            app.InputLabel_3 = uilabel(app.Panel_11);
            app.InputLabel_3.Text = 'Input';
            app.BrowseFileButton_4 = uibutton(app.Panel_11, 'push');
            app.BrowseFileButton_4.ButtonPushedFcn = createCallbackFcn(app, @set_img_brightening, true);
            app.BrowseFileButton_4.Text = 'Browse File';
            app.sarbLabel = uilabel(app.Panel_11);
            app.sarbLabel.Text = 's = ar + b';
            app.ComputeButton = uibutton(app.Panel_11, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @compute_brightening, true);
            app.ComputeButton.Text = 'Compute';
            app.aEditFieldLabel = uilabel(app.Panel_11);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_11, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @set_a_brightening, true);
            app.bEditFieldLabel = uilabel(app.Panel_11);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_11, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @set_b_brightening, true);
            app.Panel_12 = uipanel(app.ImageBrighteningTab);
            app.UIAxes_BrightningIn = uiaxes(app.Panel_12);
            title(app.UIAxes_BrightningIn, 'Input Histogram')
            zlabel(app.UIAxes_BrightningIn, 'Z')
            app.Panel_13 = uipanel(app.ImageBrighteningTab);
            app.Image_BrighteningOut = uiimage(app.Panel_13);
            app.OutputLabel_4 = uilabel(app.Panel_13);
            app.OutputLabel_4.Text = 'Output';
            app.Panel_14 = uipanel(app.ImageBrighteningTab);
            app.UIAxes_BrighteningOut = uiaxes(app.Panel_14);
            title(app.UIAxes_BrighteningOut, 'Output Histogram')
            zlabel(app.UIAxes_BrighteningOut, 'Z')
            app.LogTransformationTab = uitab(app.TabGroup2);
            app.LogTransformationTab.Title = 'Log Transformation';
            app.Panel_15 = uipanel(app.LogTransformationTab);
            app.Image_LogIn = uiimage(app.Panel_15);
            app.InputLabel_4 = uilabel(app.Panel_15);
            app.InputLabel_4.Text = 'Input';
            app.BrowseFileButton_5 = uibutton(app.Panel_15, 'push');
            app.BrowseFileButton_5.ButtonPushedFcn = createCallbackFcn(app, @set_img_log_transformation, true);
            app.BrowseFileButton_5.Text = 'Browse File';
            app.sclog1rLabel = uilabel(app.Panel_15);
            app.sclog1rLabel.Text = 's = c * log(1 + r)';
            app.ComputeButton_2 = uibutton(app.Panel_15, 'push');
            app.ComputeButton_2.ButtonPushedFcn = createCallbackFcn(app, @compute_log_transform, true);
            app.ComputeButton_2.Text = 'Compute';
            app.cEditFieldLabel = uilabel(app.Panel_15);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel_15, 'numeric');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @set_c_log_transform, true);
            app.Panel_16 = uipanel(app.LogTransformationTab);
            app.UIAxes_LogIn = uiaxes(app.Panel_16);
            title(app.UIAxes_LogIn, 'Input Histogram')
            zlabel(app.UIAxes_LogIn, 'Z')
            app.Panel_17 = uipanel(app.LogTransformationTab);
            app.Image_LogOut = uiimage(app.Panel_17);
            app.OutputLabel_5 = uilabel(app.Panel_17);
            app.OutputLabel_5.Text = 'Output';
            app.Panel_18 = uipanel(app.LogTransformationTab);
            app.UIAxes_LogOut = uiaxes(app.Panel_18);
            title(app.UIAxes_LogOut, 'Output Histogram')
            zlabel(app.UIAxes_LogOut, 'Z')
            app.PowerTransformationTab = uitab(app.TabGroup2);
            app.PowerTransformationTab.Title = 'Power Transformation';
            app.Panel_19 = uipanel(app.PowerTransformationTab);
            app.Image_PowerIn = uiimage(app.Panel_19);
            app.InputLabel_5 = uilabel(app.Panel_19);
            app.InputLabel_5.Text = 'Input';
            app.BrowseFileButton_6 = uibutton(app.Panel_19, 'push');
            app.BrowseFileButton_6.ButtonPushedFcn = createCallbackFcn(app, @set_img_power_transform, true);
            app.BrowseFileButton_6.Text = 'Browse File';
            app.scryLabel = uilabel(app.Panel_19);
            app.scryLabel.Text = 's = c * (r ^ y)';
            app.ComputeButton_3 = uibutton(app.Panel_19, 'push');
            app.ComputeButton_3.ButtonPushedFcn = createCallbackFcn(app, @compute_power_transform, true);
            app.ComputeButton_3.Text = 'Compute';
            app.cEditField_2Label = uilabel(app.Panel_19);
            app.cEditField_2Label.Text = 'c';
            app.cEditField_2 = uieditfield(app.Panel_19, 'numeric');
            app.cEditField_2.ValueChangedFcn = createCallbackFcn(app, @set_c_power_transform, true);
            app.yEditFieldLabel = uilabel(app.Panel_19);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.Panel_19, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @set_y_power_transform, true);
            app.Panel_20 = uipanel(app.PowerTransformationTab);
            app.UIAxes_PowerIn = uiaxes(app.Panel_20);
            title(app.UIAxes_PowerIn, 'Input Histogram')
            zlabel(app.UIAxes_PowerIn, 'Z')
            app.Panel_21 = uipanel(app.PowerTransformationTab);
            app.Image_PowerOut = uiimage(app.Panel_21);
            app.OutputLabel_6 = uilabel(app.Panel_21);
            app.OutputLabel_6.Text = 'Output';
            app.Panel_22 = uipanel(app.PowerTransformationTab);
            app.UIAxes_PowerOut = uiaxes(app.Panel_22);
            title(app.UIAxes_PowerOut, 'Output Histogram')
            zlabel(app.UIAxes_PowerOut, 'Z')
            app.ContrastStretchingTab = uitab(app.TabGroup2);
            app.ContrastStretchingTab.Title = 'Contrast Stretching';
            app.Panel_23 = uipanel(app.ContrastStretchingTab);
            app.Image_ContrastIn = uiimage(app.Panel_23);
            app.InputLabel_6 = uilabel(app.Panel_23);
            app.InputLabel_6.Text = 'Input';
            app.BrowseFileButton_7 = uibutton(app.Panel_23, 'push');
            app.BrowseFileButton_7.ButtonPushedFcn = createCallbackFcn(app, @set_img_contrast_stretching, true);
            app.BrowseFileButton_7.Text = 'Browse File';
            app.Panel_24 = uipanel(app.ContrastStretchingTab);
            app.UIAxes_ContrastIn = uiaxes(app.Panel_24);
            title(app.UIAxes_ContrastIn, 'Input Histogram')
            zlabel(app.UIAxes_ContrastIn, 'Z')
            app.Panel_25 = uipanel(app.ContrastStretchingTab);
            app.Image_ContrastOut = uiimage(app.Panel_25);
            app.OutputLabel_7 = uilabel(app.Panel_25);
            app.OutputLabel_7.Text = 'Output';
            app.Panel_26 = uipanel(app.ContrastStretchingTab);
            app.UIAxes_ContrastOut = uiaxes(app.Panel_26);
            title(app.UIAxes_ContrastOut, 'Output Histogram')
            zlabel(app.UIAxes_ContrastOut, 'Z')
            app.HistogramEqualizationTab = uitab(app.TabGroup);
            app.HistogramEqualizationTab.Title = 'Histogram Equalization';
            app.Panel = uipanel(app.HistogramEqualizationTab);
            app.Image_HistEqualIizationIn = uiimage(app.Panel);
            app.InputLabel = uilabel(app.Panel);
            app.InputLabel.Text = 'Input';
            app.BrowseFileButton = uibutton(app.Panel, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @hist_equalize, true);
            app.BrowseFileButton.Text = 'Browse File';
            app.Panel_2 = uipanel(app.HistogramEqualizationTab);
            app.Image_HistEqualIizationOut = uiimage(app.Panel_2);
            app.OutputLabel = uilabel(app.Panel_2);
            app.OutputLabel.Text = 'Output';
            app.Panel_3 = uipanel(app.HistogramEqualizationTab);
            app.UIAxes_HistEqualizationIn = uiaxes(app.Panel_3);
            title(app.UIAxes_HistEqualizationIn, 'Input Histogram')
            zlabel(app.UIAxes_HistEqualizationIn, 'Z')
            app.Panel_4 = uipanel(app.HistogramEqualizationTab);
            app.UIAxes_HistEqualizationOut = uiaxes(app.Panel_4);
            title(app.UIAxes_HistEqualizationOut, 'Output Histogram')
            zlabel(app.UIAxes_HistEqualizationOut, 'Z')
            app.HistogramSpecificationTab = uitab(app.TabGroup);
            app.HistogramSpecificationTab.Title = 'Histogram Specification';
            app.Panel_5 = uipanel(app.HistogramSpecificationTab);
            app.Image_HistSpecIn = uiimage(app.Panel_5);
            app.InputLabel_2 = uilabel(app.Panel_5);
            app.InputLabel_2.Text = 'Input';
            app.BrowseFileButton_2 = uibutton(app.Panel_5, 'push');
            app.BrowseFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @hist_specify, true);
            app.BrowseFileButton_2.Text = 'Browse File';
            app.Panel_6 = uipanel(app.HistogramSpecificationTab);
            app.Image_HistSpecRef = uiimage(app.Panel_6);
            app.ReferenceLabel = uilabel(app.Panel_6);
            app.ReferenceLabel.Text = 'Reference';
            app.BrowseFileButton_3 = uibutton(app.Panel_6, 'push');
            app.BrowseFileButton_3.ButtonPushedFcn = createCallbackFcn(app, @hist_specify_ref, true);
            app.BrowseFileButton_3.Text = 'Browse File';
            app.Panel_7 = uipanel(app.HistogramSpecificationTab);
            app.UIAxes_HistSpecIn = uiaxes(app.Panel_7);
            title(app.UIAxes_HistSpecIn, 'Input Histogram')
            zlabel(app.UIAxes_HistSpecIn, 'Z')
            app.Panel_8 = uipanel(app.HistogramSpecificationTab);
            app.UIAxes_HistSpecRef = uiaxes(app.Panel_8);
            title(app.UIAxes_HistSpecRef, 'Reference Histogram')
            zlabel(app.UIAxes_HistSpecRef, 'Z')
            app.Panel_9 = uipanel(app.HistogramSpecificationTab);
            app.Image_HistSpecOut = uiimage(app.Panel_9);
            app.OutputLabel_3 = uilabel(app.Panel_9);
            app.OutputLabel_3.Text = 'Output';
            app.Panel_10 = uipanel(app.HistogramSpecificationTab);
            app.UIAxes_HistSpecOut = uiaxes(app.Panel_10);
            title(app.UIAxes_HistSpecOut, 'Output Histogram')
            zlabel(app.UIAxes_HistSpecOut, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end